classdef nearestPoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        NewStartPoint             matlab.ui.control.Button
        ChecksPanel               matlab.ui.container.Panel
        notGoodZone               matlab.ui.control.Image
        GoodZone                  matlab.ui.control.Image
        GoodZoneButton            matlab.ui.control.Button
        AlgorithmPanel            matlab.ui.container.Panel
        RunsEditField             matlab.ui.control.NumericEditField
        RunsEditFieldLabel        matlab.ui.control.Label
        InstantCheckBox           matlab.ui.control.CheckBox
        AlgorithmStart            matlab.ui.control.RockerSwitch
        EnableSwitchLabel         matlab.ui.control.Label
        AlgorithmDropDown         matlab.ui.control.DropDown
        AlgorithmDropDownLabel    matlab.ui.control.Label
        MovesPanel                matlab.ui.container.Panel
        PlotcircleatpointButton   matlab.ui.control.Button
        closeMem                  matlab.ui.control.Button
        closeSoft                 matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        CurrentRunEditField       matlab.ui.control.NumericEditField
        CurrentRunEditFieldLabel  matlab.ui.control.Label
        IterationsEditField       matlab.ui.control.NumericEditField
        IterationsEditFieldLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        FileMenu                  matlab.ui.container.Menu
        ViewMenu                  matlab.ui.container.Menu
        AreasMenu                 matlab.ui.container.Menu
        DefaultViewMenu           matlab.ui.container.Menu
        ShowAreaUnderCurvesMenu   matlab.ui.container.Menu
        ShowGoodZoneMenu          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        xmin = -10;
        xmax = 10;
        ymin = -10; 
        ymax = 10; 

        currentX;
        currentY; 
        lastX;
        lastY;

        x = 0:0.1:10; % Used for plotting, may be unneeded?

        curve_memristor;
        curve_software;

        iterations;

        autoDelay = 'false';
        autoSwitch = 'false';
    end
    
    methods (Access = private)
        
        
        function point = closestPoint(app, xin, yin, curve)
 
            syms x y
            dfx = sqrt((x - xin)^2 + (curve - yin)^2);
            D = diff(dfx);
            simplify(D);
            eqn = D == 0;
            dist = vpa(solve(eqn, x));
            dist(dist~=real(dist)) = NaN;
            dist = min(dist, [], 'omitnan')
            x = dist(1);
            y = eval(curve);

            point = [x, y];


        end
        

        % menuname should be in format of "app.menu3" or whatever it's called
        % example: toggleCheckMenu(app, app.DefaultViewMenu)
        function toggleCheckMenu(app, menuName) 
            isChecked = menuName.Checked;
            if isequal(isChecked,'on')
                menuName.Checked = 'off';
            elseif isequal(isChecked,'off')
                menuName.Checked = 'on';
            end
        
        end
        
        function result = areWeGood(app)
            % basically use vpa solve to see if a point is < the same point on the
            % above curve but > than the same point on the lower curve

            x = app.currentX; % dont "fix" this, we're trying to use x as a symbolic variable
            y = app.currentY;
            topY = eval(app.curve_software);
            bottomY = eval(app.curve_memristor);

            if (bottomY <= y && y <= topY)
                result = "true";
            else
                result = "false";
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Random Curve
            syms x y
            curve1 = 0.5*x^3 - 3*x^2 + 0.4*x;
            ezcurve = x^5 + x^3 - 6;

            app.curve_memristor = ezcurve;
            app.curve_software = 0.5*x^3 -2*x^2 + 3*x - 3;

            fplot(app.UIAxes, app.curve_memristor);
            fplot(app.UIAxes, app.curve_software);
            
            app.DefaultViewMenu.Checked = 'on';
            app.ShowAreaUnderCurvesMenu.Checked = 'off';
            app.ShowGoodZoneMenu.Checked = 'off';

            app.iterations = 0;

            app.currentX = NaN;
            app.currentY = NaN;
        end

        % Button pushed function: NewStartPoint
        function NewStartPointButtonPushed(app, event)
            app.lastX = 0; 
            app.lastY = 0;
            
            app.currentX = randi([app.xmin app.xmax]);
            app.currentY = randi([app.ymin app.ymax]);
            plot(app.UIAxes, app.currentX, app.currentY, 'x', 'Color', 'black');
            app.iterations = 0;
            app.IterationsEditField.Value = app.iterations;
        end

        % Button pushed function: closeMem
        function closeMemButtonPushed(app, event)
            if (isnan(app.currentX) || isnan(app.currentY)) % Makes a new point only if we need it
                    NewStartPointButtonPushed(app, 'null');
                    pause(0.3);
            end
            
            point = closestPoint(app, app.currentX, app.currentY, app.curve_memristor); % find the closest point
            plot(app.UIAxes, point(1), point(2), 'o', 'Color', 'black'); % Plot the point on the figure
            app.lastX = app.currentX;
            app.lastY = app.currentY;
            app.currentX = point(1);
            app.currentY = point(2);
            app.iterations = app.iterations + 1;
            app.IterationsEditField.Value = app.iterations;
        end

        % Button pushed function: closeSoft
        function closeSoftButtonPushed(app, event)
            if (isnan(app.currentX) || isnan(app.currentY)) % Makes a new point only if we need it
                    NewStartPointButtonPushed(app, 'null');
                    pause(0.3);
            end

            point = closestPoint(app, app.currentX, app.currentY, app.curve_software); % find the closest point
            plot(app.UIAxes, point(1), point(2), 'o', 'Color', 'black'); % Plot the point on the figure
            app.lastX = app.currentX;
            app.lastY = app.currentY;
            app.currentX = point(1);
            app.currentY = point(2);
            app.iterations = app.iterations + 1;
            app.IterationsEditField.Value = app.iterations;
        end

        % Value changed function: InstantCheckBox
        function InstantCheckBoxValueChanged(app, event)
            value = app.InstantCheckBox.Value;
            
            app.autoDelay ~= app.autoDelay;
        end

        % Value changed function: AlgorithmStart
        function AlgorithmStartValueChanged(app, event)
            if (app.AlgorithmStart.Value == "On") % if switch is on
                app.AlgorithmDropDown.Enable = 'off'; % Don't switch algorithms once started
                app.RunsEditField.Enable = 'off';

%                if (isnan(app.currentX) || isnan(app.currentY)) % Makes a new point only if we need it
%                    NewStartPointButtonPushed(app, 'null');
%                    pause(0.3);
%                end
                for i = 1:app.RunsEditField.Value
                    app.CurrentRunEditField.Value = i;
                    NewStartPointButtonPushed(app, 'null'); % Make a new point
                    
                    switch app.AlgorithmDropDown.Value % current issue where if generates an incorrect point it just makes a new one. 
                        case 'Nearest Point'
    
                            lastMove = "none"; % program to be an array that tracks all points so far for comparison, or maybe just the last 3, to see if its in a valley or parallel area or whatever
                            points = [[]];
                            while (areWeGood(app) == "false") % Start algorithm until good zone reached. This can be placed outside the switch if i decide that all algorithms will check after each run
                                % First, detect a pattern, if any. Then, make a move based on that pattern
                                if false % valley
                                      do stuff
                                elseif false % parallel
                                      do stuff
                                else % no pattern detected
                                    if (lastMove == "mem") 
                                        closeSoftButtonPushed(app, 'null');
                                        %lastMove = "soft"
                                    elseif (lastMove == "soft")
                                        closeMemButtonPushed(app, 'null');
                                        %lastMove = "mem"
                                    else % will default here on first iteration
                                        closeSoftButtonPushed(app, 'null');
                                        %lastMove = "soft"
                                    end
                                 points = [{app.currentX, app.currentY}, points]

                                end

                                pause(0.3);
        
                            end % end while loop
    

                        case 'Option 2'
                            do nothing i guess;
                        otherwise
                            idk bro;
                    end % end algorithm switch

                end % end runs loop
                
                % Reset things now that the runs are done
                app.currentX = NaN;
                app.currentY = NaN;

                app.GoodZone.Visible = 'on';
                pause(5);
                app.GoodZone.Visible = 'off';
            end 

            app.AlgorithmDropDown.Enable = 'on'; % Makes sure you can choose an algorithm
            app.AlgorithmStart.Value = "Off"; % Turn switch off when done
            app.RunsEditField.Enable = 'on';
        end

        % Button pushed function: PlotcircleatpointButton
        function PlotcircleatpointButtonPushed(app, event)
            x = app.lastX;
            y = app.lastY;
            r = sqrt((app.currentX - app.lastX)^2 + (app.currentY - app.lastY)^2);

            ang=0:0.01:2*pi; 
            xp=r*cos(ang);
            yp=r*sin(ang);
            plot(app.UIAxes, x+xp,y+yp);
        end

        % Menu selected function: DefaultViewMenu
        function DefaultViewMenuSelected(app, event)
            app.DefaultViewMenu.Checked = 'on';
            app.ShowAreaUnderCurvesMenu.Checked = 'off';
            app.ShowGoodZoneMenu.Checked = 'off';
        end

        % Menu selected function: ShowAreaUnderCurvesMenu
        function ShowAreaUnderCurvesMenuSelected(app, event)
            app.DefaultViewMenu.Checked = 'off';
            app.ShowAreaUnderCurvesMenu.Checked = 'on';
            app.ShowGoodZoneMenu.Checked = 'off'; 
%{            
            x = 0:0.1:10;
            X = [x, fliplr(0)];
            Y = [x, fliplr(x.^5 + x.^3 - 6)];
            fill(app.UIAxes, X, Y, 'b');
%}
            %{
            x = 1:300;
%            curve1 = log(x)
            curve1 = matlabFunction(app.curve_memristor)
%            curve2 = 2*log(x)
            curve2 = matlabFunction(app.curve_software)
%            plot(x, curve1, 'r', 'LineWidth', 2);
%            hold on;
%            plot(x, curve2, 'b', 'LineWidth', 2);
            x2 = [x, fliplr(x)];
            inBetween = [curve1, fliplr(curve2)];
            fill(app.UIAxes, x2, inBetween, 'g');
            %}
            
            f{1} = app.curve_memristor; % declare as cell array {} of string ''
            h3 = fplot(f{1},[-10, 10]);   %the correct way to call ezplot
            x = get(h3, 'XData');          %get the x and y data
            y = get(h3, 'YData');
            area(app.UIAxes, x, y);   %plot the (x,y) area in red
        end

        % Menu selected function: ShowGoodZoneMenu
        function ShowGoodZoneMenuSelected(app, event)
            app.DefaultViewMenu.Checked = 'off';
            app.ShowAreaUnderCurvesMenu.Checked = 'off';
            app.ShowGoodZoneMenu.Checked = 'on';
        end

        % Button pushed function: GoodZoneButton
        function GoodZoneButtonPushed(app, event)
            if (areWeGood(app) == "true")
                app.GoodZone.Visible = 'on';
                pause(5);
                app.GoodZone.Visible = 'off';
            else
                app.notGoodZone.Visible = 'on';
                pause(5);
                app.notGoodZone.Visible = 'off';
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MovesPanel = uipanel(app.LeftPanel);
            app.MovesPanel.Title = 'Moves';
            app.closeSoft = uibutton(app.MovesPanel, 'push');
            app.closeSoft.ButtonPushedFcn = createCallbackFcn(app, @closeSoftButtonPushed, true);
            app.closeSoft.Text = 'Go to Closest Point on Software Curve';
            app.closeMem = uibutton(app.MovesPanel, 'push');
            app.closeMem.ButtonPushedFcn = createCallbackFcn(app, @closeMemButtonPushed, true);
            app.closeMem.Text = 'Go to Closest Point on Memristor Curve';
            app.PlotcircleatpointButton = uibutton(app.MovesPanel, 'push');
            app.PlotcircleatpointButton.ButtonPushedFcn = createCallbackFcn(app, @PlotcircleatpointButtonPushed, true);
            app.PlotcircleatpointButton.Text = {'Plot circle'; 'at point'};
            app.AlgorithmPanel = uipanel(app.LeftPanel);
            app.AlgorithmPanel.Title = 'Algorithm';
            app.AlgorithmDropDownLabel = uilabel(app.AlgorithmPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.AlgorithmPanel);
            app.AlgorithmDropDown.Items = {'Nearest Point', 'Option 2', 'Option 3', 'Option 4'};
            app.AlgorithmDropDown.Value = 'Nearest Point';
            app.EnableSwitchLabel = uilabel(app.AlgorithmPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.AlgorithmStart = uiswitch(app.AlgorithmPanel, 'rocker');
            app.AlgorithmStart.ValueChangedFcn = createCallbackFcn(app, @AlgorithmStartValueChanged, true);
            app.InstantCheckBox = uicheckbox(app.AlgorithmPanel);
            app.InstantCheckBox.ValueChangedFcn = createCallbackFcn(app, @InstantCheckBoxValueChanged, true);
            app.InstantCheckBox.Text = 'Instant';
            app.RunsEditFieldLabel = uilabel(app.AlgorithmPanel);
            app.RunsEditFieldLabel.Text = 'Runs';
            app.RunsEditField = uieditfield(app.AlgorithmPanel, 'numeric');
            app.RunsEditField.Value = 1;
            app.ChecksPanel = uipanel(app.LeftPanel);
            app.ChecksPanel.Title = 'Checks';
            app.GoodZoneButton = uibutton(app.ChecksPanel, 'push');
            app.GoodZoneButton.ButtonPushedFcn = createCallbackFcn(app, @GoodZoneButtonPushed, true);
            app.GoodZoneButton.Text = 'Good Zone???';
            app.GoodZone = uiimage(app.ChecksPanel);
            app.GoodZone.Visible = 'off';
            app.GoodZone.ImageSource = 'check.gif';
            app.notGoodZone = uiimage(app.ChecksPanel);
            app.notGoodZone.Visible = 'off';
            app.notGoodZone.ImageSource = 'no.gif';
            app.NewStartPoint = uibutton(app.LeftPanel, 'push');
            app.NewStartPoint.ButtonPushedFcn = createCallbackFcn(app, @NewStartPointButtonPushed, true);
            app.NewStartPoint.Text = 'New Starting Point';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Nearest-Point Algorithm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.XTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.IterationsEditFieldLabel = uilabel(app.RightPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.RightPanel, 'numeric');
            app.IterationsEditField.Editable = 'off';
            app.CurrentRunEditFieldLabel = uilabel(app.RightPanel);
            app.CurrentRunEditFieldLabel.Text = 'Current Run';
            app.CurrentRunEditField = uieditfield(app.RightPanel, 'numeric');
            app.CurrentRunEditField.Editable = 'off';
            app.CurrentRunEditField.Value = 1;
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.AreasMenu = uimenu(app.ViewMenu);
            app.AreasMenu.Text = 'Areas';
            app.DefaultViewMenu = uimenu(app.AreasMenu);
            app.DefaultViewMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultViewMenuSelected, true);
            app.DefaultViewMenu.Checked = 'on';
            app.DefaultViewMenu.Text = 'Default View';
            app.ShowAreaUnderCurvesMenu = uimenu(app.AreasMenu);
            app.ShowAreaUnderCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAreaUnderCurvesMenuSelected, true);
            app.ShowAreaUnderCurvesMenu.Checked = 'on';
            app.ShowAreaUnderCurvesMenu.Text = 'Show Area Under Curves';
            app.ShowGoodZoneMenu = uimenu(app.AreasMenu);
            app.ShowGoodZoneMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowGoodZoneMenuSelected, true);
            app.ShowGoodZoneMenu.Checked = 'on';
            app.ShowGoodZoneMenu.Text = 'Show "Good Zone"';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nearestPoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end